classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        LoginTab                matlab.ui.container.Tab
        LoginButton             matlab.ui.control.Button
        PasswordEditField       matlab.ui.control.EditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        UserEditField           matlab.ui.control.EditField
        UserEditFieldLabel      matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        AdquisicionTab          matlab.ui.container.Tab
        GuardarDatosButton      matlab.ui.control.Button
        CONEXIONSERIALButton    matlab.ui.control.Button
        DataRead                matlab.ui.control.Label
        ADQUISICIONLabel        matlab.ui.control.Label
        GraficaViento           matlab.ui.control.UIAxes
        GraficaTemperatura      matlab.ui.control.UIAxes
        GraficaLuz              matlab.ui.control.UIAxes
        ANALISISTab             matlab.ui.container.Tab
        MedCheckBox             matlab.ui.control.CheckBox
        MaxCheckBox             matlab.ui.control.CheckBox
        MinCheckBox             matlab.ui.control.CheckBox
        XaxisDropDown           matlab.ui.control.DropDown
        XaxisDropDownLabel      matlab.ui.control.Label
        YaxisDropDown           matlab.ui.control.DropDown
        YaxisDropDownLabel      matlab.ui.control.Label
        CargarDatosButton       matlab.ui.control.Button
        ANALISISLabel           matlab.ui.control.Label
        GraficaData             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dataCollection = [];
        dataYaxis = [];
        dataXaxis = [];
        luz = [];
        temperatura = [];
        viento = [];
        tiempo = [];
        minobj = [];
        maxobj = [];
        medobj = [];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AdquisicionTab.Parent = [];
            app.ANALISISTab.Parent = [];
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            user = app.UserEditField.Value;
            pass = app.PasswordEditField.Value;
            if (user == "juan" && pass == "pass123")
                %%pass tab
                app.LoginTab.Parent = [];
                app.AdquisicionTab.Parent = app.TabGroup;
                app.ANALISISTab.Parent = app.TabGroup;
                app.TabGroup.SelectedTab = app.AdquisicionTab;
            else
                app.UserEditField.Value = "Datos erroneos";
                app.PasswordEditField.Value = "Datos erroneos";
            end
        end

        % Button pushed function: CONEXIONSERIALButton
        function CONEXIONSERIALButtonPushed(app, event)
            s = serialport('COM3',9600);
            fopen(s);
            i = 0;
            while i < 1000
            readData = fscanf(s);
            data = split(readData,";");
            app.DataRead.Text = readData;
            %%data = Operacion;100ms;Celsius;Grados;216;16.6;32
            if( string(data(1)) == "Operacion" )
                x1 = cellfun(@str2num,data(5));
                x2 = cellfun(@str2num,data(6));
                x3 = cellfun(@str2num,data(7));
                plot(app.GraficaLuz, i,x1,'b--o');
                hold(app.GraficaLuz, 'on');
                ylabel(app.GraficaTemperatura, string(data(3)));
                plot(app.GraficaTemperatura,i,x2,'b--o');
                hold(app.GraficaTemperatura, 'on');
                ylabel(app.GraficaViento, string(data(4)));
                plot(app.GraficaViento,i,x3,'b--o');
                hold(app.GraficaViento, 'on');
                q = [ x1 x2 x3 i ];
                app.dataCollection=[app.dataCollection;q];
                i = i+1;
            end
            pause(1);
            end
            hold(app.GraficaTemperatura, 'off');
            hold(app.GraficaLuz, 'off');
            hold(app.GraficaViento, 'off');
            fclose(s);
            delete(s);
        end

        % Button pushed function: GuardarDatosButton
        function GuardarDatosButtonPushed(app, event)
            %%save data
            writematrix(app.dataCollection,'data.xls')
            
        end

        % Button pushed function: CargarDatosButton
        function CargarDatosButtonPushed(app, event)
            data = readmatrix('data.xls');
            %% luz,temperatura,viento,tiempo
            app.luz = data(:,1);
            app.temperatura = data(:,2);
            app.viento = data(:,3);
            app.tiempo = data(:,4);
            app.dataYaxis = app.luz;
            app.dataXaxis = app.tiempo;
            plot(app.GraficaData,app.dataXaxis,app.dataYaxis,'b--o');
            
        end

        % Value changed function: YaxisDropDown
        function YaxisDropDownValueChanged(app, event)
            value = app.YaxisDropDown.Value;
            switch value 
                case 'Tiempo'
                    app.dataYaxis = app.tiempo;
                case 'Temperatura'
                    app.dataYaxis = app.temperatura;
                case 'Luz'
                    app.dataYaxis = app.luz;
                case 'Viento'
                    app.dataYaxis = app.viento;
            end

            plot(app.GraficaData,app.dataXaxis,app.dataYaxis,'b--o');
            
        end

        % Value changed function: XaxisDropDown
        function XaxisDropDownValueChanged(app, event)
            value = app.XaxisDropDown.Value;
            
            switch value 
                case 'Tiempo'
                    app.dataXaxis = app.tiempo;
                case 'Temperatura'
                    app.dataXaxis = app.temperatura;
                case 'Luz'
                    app.dataXaxis = app.luz;
                case 'Viento'
                    app.dataXaxis = app.viento;
            end

            plot(app.GraficaData,app.dataXaxis,app.dataYaxis,'b--o'); 
        end

        % Value changed function: MinCheckBox
        function MinCheckBoxValueChanged(app, event)
            value = app.MinCheckBox.Value;
            [miny, mindex] = min(app.dataYaxis);
            minx = app.dataXaxis(mindex);
            if value
                app.minobj = text(app.GraficaData,minx,miny,'min',Color='b',FontSize=25);
            else
                delete(app.minobj);
            end
        end

        % Value changed function: MaxCheckBox
        function MaxCheckBoxValueChanged(app, event)
            value = app.MaxCheckBox.Value;
            [maxy, index] = max(app.dataYaxis);
            maxx = app.dataXaxis(index);
            if value
                app.maxobj = text(app.GraficaData,maxx,maxy,'max',Color='r',FontSize=25);
            else
                delete(app.maxobj);
            end
        end

        % Value changed function: MedCheckBox
        function MedCheckBoxValueChanged(app, event)
            value = app.MedCheckBox.Value;
            medy = median(app.dataYaxis);
            medx = app.dataXaxis(find(app.dataYaxis == medy,1,'first'));
            if value
                app.medobj = text(app.GraficaData,medx,medy,'median',Color='g',FontSize=25);
            else
                delete(app.medobj);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoginTab = uitab(app.TabGroup);
            app.LoginTab.Title = 'Login';
            app.Image = uiimage(app.LoginTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'loginUser.png');
            app.UserEditFieldLabel = uilabel(app.LoginTab);
            app.UserEditFieldLabel.Text = 'User:';
            app.UserEditField = uieditfield(app.LoginTab, 'text');
            app.PasswordEditFieldLabel = uilabel(app.LoginTab);
            app.PasswordEditFieldLabel.Text = 'Password:';
            app.PasswordEditField = uieditfield(app.LoginTab, 'text');
            app.LoginButton = uibutton(app.LoginTab, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.AdquisicionTab = uitab(app.TabGroup);
            app.AdquisicionTab.Title = 'Adquisicion';
            app.GraficaLuz = uiaxes(app.AdquisicionTab);
            title(app.GraficaLuz, 'Luz')
            xlabel(app.GraficaLuz, 'X')
            ylabel(app.GraficaLuz, 'Lux')
            zlabel(app.GraficaLuz, 'Z')
            app.GraficaTemperatura = uiaxes(app.AdquisicionTab);
            title(app.GraficaTemperatura, 'Temperatura')
            xlabel(app.GraficaTemperatura, 'X')
            ylabel(app.GraficaTemperatura, 'Y')
            zlabel(app.GraficaTemperatura, 'Z')
            app.GraficaViento = uiaxes(app.AdquisicionTab);
            title(app.GraficaViento, 'Viento')
            xlabel(app.GraficaViento, 'X')
            ylabel(app.GraficaViento, 'Y')
            zlabel(app.GraficaViento, 'Z')
            app.ADQUISICIONLabel = uilabel(app.AdquisicionTab);
            app.ADQUISICIONLabel.Text = 'ADQUISICION';
            app.DataRead = uilabel(app.AdquisicionTab);
            app.DataRead.Text = '';
            app.CONEXIONSERIALButton = uibutton(app.AdquisicionTab, 'push');
            app.CONEXIONSERIALButton.ButtonPushedFcn = createCallbackFcn(app, @CONEXIONSERIALButtonPushed, true);
            app.CONEXIONSERIALButton.Text = 'CONEXION SERIAL';
            app.GuardarDatosButton = uibutton(app.AdquisicionTab, 'push');
            app.GuardarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarDatosButtonPushed, true);
            app.GuardarDatosButton.Text = 'Guardar Datos';
            app.ANALISISTab = uitab(app.TabGroup);
            app.ANALISISTab.Title = 'ANALISIS';
            app.GraficaData = uiaxes(app.ANALISISTab);
            title(app.GraficaData, 'Title')
            xlabel(app.GraficaData, 'X')
            ylabel(app.GraficaData, 'Y')
            zlabel(app.GraficaData, 'Z')
            app.ANALISISLabel = uilabel(app.ANALISISTab);
            app.ANALISISLabel.Text = 'ANALISIS';
            app.CargarDatosButton = uibutton(app.ANALISISTab, 'push');
            app.CargarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargarDatosButtonPushed, true);
            app.CargarDatosButton.Text = 'Cargar Datos';
            app.YaxisDropDownLabel = uilabel(app.ANALISISTab);
            app.YaxisDropDownLabel.Text = 'Y axis';
            app.YaxisDropDown = uidropdown(app.ANALISISTab);
            app.YaxisDropDown.Items = {'Tiempo', 'Temperatura', 'Luz', 'Viento'};
            app.YaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YaxisDropDownValueChanged, true);
            app.YaxisDropDown.Value = 'Tiempo';
            app.XaxisDropDownLabel = uilabel(app.ANALISISTab);
            app.XaxisDropDownLabel.Text = 'X axis';
            app.XaxisDropDown = uidropdown(app.ANALISISTab);
            app.XaxisDropDown.Items = {'Tiempo', 'Temperatura', 'Luz', 'Viento'};
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDownValueChanged, true);
            app.XaxisDropDown.Value = 'Temperatura';
            app.MinCheckBox = uicheckbox(app.ANALISISTab);
            app.MinCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinCheckBoxValueChanged, true);
            app.MinCheckBox.Text = 'Min';
            app.MaxCheckBox = uicheckbox(app.ANALISISTab);
            app.MaxCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaxCheckBoxValueChanged, true);
            app.MaxCheckBox.Text = 'Max';
            app.MedCheckBox = uicheckbox(app.ANALISISTab);
            app.MedCheckBox.ValueChangedFcn = createCallbackFcn(app, @MedCheckBoxValueChanged, true);
            app.MedCheckBox.Text = 'Med';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end